classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrocarButton                    matlab.ui.control.Button
        AbrirButton                     matlab.ui.control.Button
        TipoGraficoListBox              matlab.ui.control.ListBox
        TipoGraficoListBoxLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PassaBaixaTab                   matlab.ui.container.Tab
        VerificarButton                 matlab.ui.control.Button
        FrequncialimitedabandadetransioEditField  matlab.ui.control.NumericEditField
        FrequncialimitedabandadetransioEditFieldLabel  matlab.ui.control.Label
        FrequncialimitedabandadepassagemEditField  matlab.ui.control.NumericEditField
        FrequncialimitedabandadepassagemEditFieldLabel  matlab.ui.control.Label
        FrequnciadeamostragemEditField  matlab.ui.control.NumericEditField
        FrequnciadeamostragemEditFieldLabel  matlab.ui.control.Label
        ParmetrosdofiltropassabaixaLabel  matlab.ui.control.Label
        ExecutarButton                  matlab.ui.control.Button
        JanelamentosListBox             matlab.ui.control.ListBox
        JanelamentosListBoxLabel        matlab.ui.control.Label
        MnimanabandaderejeioEditField   matlab.ui.control.NumericEditField
        MnimanabandaderejeioEditFieldLabel  matlab.ui.control.Label
        MximanabandadepassagemEditField  matlab.ui.control.NumericEditField
        MximanabandadepassagemEditFieldLabel  matlab.ui.control.Label
        AtenuaoLabel                    matlab.ui.control.Label
        PassaAltaTab                    matlab.ui.container.Tab
        ParmetrosdofiltropassaaltaLabel  matlab.ui.control.Label
        JanelamentosListBox_2           matlab.ui.control.ListBox
        JanelamentosListBox_2Label      matlab.ui.control.Label
        BandaderejeioEditField_2        matlab.ui.control.NumericEditField
        BandaderejeioEditField_2Label   matlab.ui.control.Label
        BandadepassagemEditField_2      matlab.ui.control.NumericEditField
        BandadepassagemEditField_2Label  matlab.ui.control.Label
        sEditField_2                    matlab.ui.control.NumericEditField
        sEditField_2Label               matlab.ui.control.Label
        pEditField_2                    matlab.ui.control.NumericEditField
        pEditField_2Label               matlab.ui.control.Label
        ExecutarButton_2                matlab.ui.control.Button
        AtenuaoLabel_2                  matlab.ui.control.Label
        PassaFaixaTab                   matlab.ui.container.Tab
        ParmetrosdofiltropassafaixaLabel  matlab.ui.control.Label
        RejeitaFaixaTab                 matlab.ui.container.Tab
        ParmetrosdofiltrorejeitafaixaLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        M; % Description
        w;
        W;
        janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
        janelas_bkp = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function chama_passabaixa(app, event)
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            app.M = 51;
            
            
            switch(getGrafico(1))
                case 1
                    title (app.UIAxes,"Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis(app.UIAxes, [0 (app.M-1) 0 1.1]);
                case 2
                    title (app.UIAxes,"Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end


            getJanelamento = get(app.JanelamentosListBox,'Value');
            
            
                %warndlg('AQUI','Warning');
                
                %[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
                
                index = str2num(getJanelamento(1));
                
                valor_selecionado = string(app.janelas(index))
                
                
                if valor_selecionado == 'Retangular'
                    [app.w,app.W] = rectangle_pbl(app.M);
                elseif valor_selecionado == 'Bartlett'
                    [app.w,app.W] = bartlett_pbl(app.M);
                elseif valor_selecionado == 'Hann'
                    [app.w,app.W] = hann_pbl(app.M);
                elseif valor_selecionado == 'Hamming'
                    [app.w,app.W] = hamming_pbl(app.M);
                elseif valor_selecionado == 'Blackman'
                    [app.w,app.W] = blackman_pbl(app.M);
                end
            
            %switch(getJanelamento(1))
                %case '1'
                 %   [app.w,app.W] = rectangle_pbl(app.M);
                %case '2'
                 %   [app.w,app.W] = bartlett_pbl(app.M);
                %case '3'
                 %   [app.w,app.W] = hann_pbl(app.M);
                %case '4'
               %     [app.w,app.W] = hamming_pbl(app.M);
              %  case '5'
             %       [app.w,app.W] = blackman_pbl(app.M);
            %end
            
            if(getGrafico(1) == 2)
                
                w2 = fft(app.w);
                
                plot(app.UIAxes, abs(w2))
            else
                stem(app.UIAxes,app.W,app.w);
            end


        end

        % Button pushed function: TrocarButton
        function trocarGrafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title (app.UIAxes,"Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis(app.UIAxes, [0 (app.M-1) 0 1.1]);
                case 2
                    title (app.UIAxes,"Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end
            
            if(getGrafico(1) == 2)
                
                v = 0:app.M;
                
                [f,w2] = my_fft2(v,app.w, 8000);
                
                plot(app.UIAxes, f,w2);
                
            else
                stem(app.UIAxes,app.W,app.w);
            end
            
        end

        % Button pushed function: AbrirButton
        function abrir_grafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case 1
                    title ("Janelamento", 'Interpreter','LaTex','FontSize',12);
                    axis([0 (app.M-1) 0 1.1]);
                case 2
                    title ("Magnitude e fase", 'Interpreter','LaTex','FontSize',12);  
            end
            
            if(getGrafico(1) == 2)
                
                w2 = fft(app.w);
                
                plot(app.W, abs(w2))
            else
                stem(app.W,app.w);
            end
        end

        % Button pushed function: VerificarButton
        function verificar_parametros_lp(app, event)
            valor = app.MnimanabandaderejeioEditField.Value;
            
            
            if valor < 22 % todos
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            elseif valor < 26 % só n retangular
                app.janelas = [cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif valor < 45 % só não retangular e bartlett
                app.janelas = [cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif valor < 54 % só não retangular, bartlett e Hann
                app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif valor < 75 % só não retangular, bartlett, Hann e Hamming
                app.janelas = [cellstr(['Blackman'])];
            else
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                warndlg('Pico do erro de aproximação maior que o recomendado para os janelamentos definidos.','Warning');
            end
            %app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
            
            %app.JanelamentosListBox.Items=[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            app.JanelamentosListBox.Items=app.janelas;
            
            app.JanelamentosListBox.Enable  = true;
            app.ExecutarButton.Enable = true;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vazio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PassaBaixaTab = uitab(app.TabGroup);
            app.PassaBaixaTab.Title = 'Passa Baixa';
            app.AtenuaoLabel = uilabel(app.PassaBaixaTab);
            app.AtenuaoLabel.Text = 'Atenuação:';
            app.MximanabandadepassagemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.MximanabandadepassagemEditFieldLabel.Text = 'Máxima na banda de passagem';
            app.MximanabandadepassagemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.MximanabandadepassagemEditField.Value = 0.1;
            app.MnimanabandaderejeioEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.MnimanabandaderejeioEditFieldLabel.Text = 'Mínima na banda de rejeição';
            app.MnimanabandaderejeioEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.MnimanabandaderejeioEditField.Value = 50;
            app.JanelamentosListBoxLabel = uilabel(app.PassaBaixaTab);
            app.JanelamentosListBoxLabel.Text = 'Janelamentos';
            app.JanelamentosListBox = uilistbox(app.PassaBaixaTab);
            app.JanelamentosListBox.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox.ItemsData = {'1', '2', '3', '4', '5'};
            app.JanelamentosListBox.Enable = 'off';
            app.JanelamentosListBox.Value = '1';
            app.ExecutarButton = uibutton(app.PassaBaixaTab, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @chama_passabaixa, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ParmetrosdofiltropassabaixaLabel = uilabel(app.PassaBaixaTab);
            app.ParmetrosdofiltropassabaixaLabel.Text = 'Parâmetros do filtro passa baixa:';
            app.FrequnciadeamostragemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequnciadeamostragemEditFieldLabel.Text = 'Frequência de amostragem';
            app.FrequnciadeamostragemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequnciadeamostragemEditField.Value = 8000;
            app.FrequncialimitedabandadepassagemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequncialimitedabandadepassagemEditFieldLabel.Text = 'Frequência limite da banda de passagem ';
            app.FrequncialimitedabandadepassagemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequncialimitedabandadepassagemEditField.Value = 1500;
            app.FrequncialimitedabandadetransioEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequncialimitedabandadetransioEditFieldLabel.Text = 'Frequência limite da banda de transição';
            app.FrequncialimitedabandadetransioEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequncialimitedabandadetransioEditField.Value = 2000;
            app.VerificarButton = uibutton(app.PassaBaixaTab, 'push');
            app.VerificarButton.ButtonPushedFcn = createCallbackFcn(app, @verificar_parametros_lp, true);
            app.VerificarButton.Text = 'Verificar';
            app.PassaAltaTab = uitab(app.TabGroup);
            app.PassaAltaTab.Title = 'Passa Alta';
            app.AtenuaoLabel_2 = uilabel(app.PassaAltaTab);
            app.AtenuaoLabel_2.Text = 'Atenuação:';
            app.ExecutarButton_2 = uibutton(app.PassaAltaTab, 'push');
            app.ExecutarButton_2.Text = 'Executar';
            app.pEditField_2Label = uilabel(app.PassaAltaTab);
            app.pEditField_2Label.Text = 'ωp';
            app.pEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.sEditField_2Label = uilabel(app.PassaAltaTab);
            app.sEditField_2Label.Text = 'ωs';
            app.sEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.BandadepassagemEditField_2Label = uilabel(app.PassaAltaTab);
            app.BandadepassagemEditField_2Label.Text = 'Banda de passagem';
            app.BandadepassagemEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.BandaderejeioEditField_2Label = uilabel(app.PassaAltaTab);
            app.BandaderejeioEditField_2Label.Text = 'Banda de rejeição';
            app.BandaderejeioEditField_2 = uieditfield(app.PassaAltaTab, 'numeric');
            app.JanelamentosListBox_2Label = uilabel(app.PassaAltaTab);
            app.JanelamentosListBox_2Label.Text = 'Janelamentos';
            app.JanelamentosListBox_2 = uilistbox(app.PassaAltaTab);
            app.JanelamentosListBox_2.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox_2.ItemsData = [1 2 3 4 5];
            app.JanelamentosListBox_2.Value = 1;
            app.ParmetrosdofiltropassaaltaLabel = uilabel(app.PassaAltaTab);
            app.ParmetrosdofiltropassaaltaLabel.Text = 'Parâmetros do filtro passa alta:';
            app.PassaFaixaTab = uitab(app.TabGroup);
            app.PassaFaixaTab.Title = 'Passa Faixa';
            app.ParmetrosdofiltropassafaixaLabel = uilabel(app.PassaFaixaTab);
            app.ParmetrosdofiltropassafaixaLabel.Text = 'Parâmetros do filtro passa faixa:';
            app.RejeitaFaixaTab = uitab(app.TabGroup);
            app.RejeitaFaixaTab.Title = 'Rejeita Faixa';
            app.ParmetrosdofiltrorejeitafaixaLabel = uilabel(app.RejeitaFaixaTab);
            app.ParmetrosdofiltrorejeitafaixaLabel.Text = 'Parâmetros do filtro rejeita faixa:';
            app.TipoGraficoListBoxLabel = uilabel(app.UIFigure);
            app.TipoGraficoListBoxLabel.Text = {'Tipo'; 'Grafico'};
            app.TipoGraficoListBox = uilistbox(app.UIFigure);
            app.TipoGraficoListBox.Items = {'Janela', 'Magnitudade e Fase', 'Item 3', 'Item 4'};
            app.TipoGraficoListBox.ItemsData = [1 2 3 4];
            app.TipoGraficoListBox.Value = 1;
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @abrir_grafico, true);
            app.AbrirButton.Enable = 'off';
            app.AbrirButton.Text = 'Abrir';
            app.TrocarButton = uibutton(app.UIFigure, 'push');
            app.TrocarButton.ButtonPushedFcn = createCallbackFcn(app, @trocarGrafico, true);
            app.TrocarButton.Enable = 'off';
            app.TrocarButton.Text = 'Trocar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end